classdef ee409_project_yeni < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SelectedgesiLabel    matlab.ui.control.Label
        RotateDropDown       matlab.ui.control.DropDown
        RotateDropDownLabel  matlab.ui.control.Label
        histogrameqButton    matlab.ui.control.Button
        binarizeButton       matlab.ui.control.Button
        Image                matlab.ui.control.Image
        exitButton           matlab.ui.control.Button
        ICCCAMSCANNERLabel   matlab.ui.control.Label
        TabGroup             matlab.ui.container.TabGroup
        Tab1                 matlab.ui.container.Tab
        UIAxes1              matlab.ui.control.UIAxes
        Tab2                 matlab.ui.container.Tab
        UIAxes2              matlab.ui.control.UIAxes
        Tab3                 matlab.ui.container.Tab
        UIAxes3              matlab.ui.control.UIAxes
        Tab4                 matlab.ui.container.Tab
        UIAxes4              matlab.ui.control.UIAxes
        Tab5                 matlab.ui.container.Tab
        UIAxes5              matlab.ui.control.UIAxes
        Tab6                 matlab.ui.container.Tab
        UIAxes6              matlab.ui.control.UIAxes
        Tab7                 matlab.ui.container.Tab
        UIAxes7              matlab.ui.control.UIAxes
        Tab8                 matlab.ui.container.Tab
        UIAxes8              matlab.ui.control.UIAxes
        Tab9                 matlab.ui.container.Tab
        UIAxes9              matlab.ui.control.UIAxes
        Tab10                matlab.ui.container.Tab
        UIAxes10             matlab.ui.control.UIAxes
        downloadButton       matlab.ui.control.Button
        ScanButton           matlab.ui.control.Button
        UploadImagesButton   matlab.ui.control.Button
    end


    properties (Access = private)
        numFiles;
        img;
        filePath;
        fileNames;
        UIAxesArray         matlab.ui.control.UIAxes
        CurrentAxesIndex;
        rot_value;
        selectedTab;
        output;

        % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImagesButton
        function UploadImagesButtonPushed(app, event)
            % Button pushed function: TakeanImageButton
            [app.fileNames, app.filePath] = uigetfile({'*.jpg;*.png;*.bmp;*.jpeg', 'Image Files'}, ...
                'Select Images', 'MultiSelect', 'on');


            if ~iscell(app.fileNames)
                app.fileNames = {app.fileNames};

            end
            app.numFiles = length(app.fileNames);

            for i = 1:app.numFiles
                % Dosyayı oku
                imagePath = fullfile(app.filePath, app.fileNames{i});
                app.img = imread(imagePath);


                % Görseli uygun uiaxes üzerinde göster
                switch i
                    case 1
                        imshow(app.img, 'Parent', app.UIAxes1);
                    case 2
                        imshow(app.img, 'Parent', app.UIAxes2);
                    case 3
                        imshow(app.img, 'Parent', app.UIAxes3);
                    case 4
                        imshow(app.img, 'Parent', app.UIAxes4);
                    case 5
                        imshow(app.img, 'Parent', app.UIAxes5);
                    case 6
                        imshow(app.img, 'Parent', app.UIAxes6);
                    case 7
                        imshow(app.img, 'Parent', app.UIAxes7);
                    case 8
                        imshow(app.img, 'Parent', app.UIAxes8);
                    case 9
                        imshow(app.img, 'Parent', app.UIAxes9);
                    case 10
                        imshow(app.img, 'Parent', app.UIAxes10);

                end
            end


        end

        % Button pushed function: exitButton
        function exitButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            for i = 1:app.numFiles
                app.img = imread(fullfile(app.filePath, app.fileNames{i}));
                [row,col,ch] = size(app.img);


                fhv = app.UIFigure.HandleVisibility;        % Current status
                app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
                set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

                [boxm] = ginput(4);                        % run ginput

                app.UIFigure.HandleVisibility = fhv;
                boxm = round(reshape(boxm, [], 2));

                % Check if the number of selected points is correct
                if size(boxm, 1) ~= 4
                    error('Please select the correct number of points.');
                end

                boxf = [1 1;
                    1 row;
                    col row;
                    col 1];
                TF = fitgeotrans(boxm, boxf, 'projective');


                % Apply the transformation to the image
                outview = imref2d(size(app.img));

                app.output= imwarp(app.img, TF, 'fillvalues', 255, 'outputview', outview);
                final_B=rgb2gray(app.output);


                switch i
                    case 1
                        imshow(final_B, 'Parent', app.UIAxes1);
                    case 2
                        imshow(final_B, 'Parent', app.UIAxes2);
                    case 3
                        imshow(final_B, 'Parent', app.UIAxes3);
                    case 4
                        imshow(final_B, 'Parent', app.UIAxes4);
                    case 5
                        imshow(final_B, 'Parent', app.UIAxes5);
                    case 6
                        imshow(final_B, 'Parent', app.UIAxes6);
                    case 7
                        imshow(final_B, 'Parent', app.UIAxes7);
                    case 8
                        imshow(final_B, 'Parent', app.UIAxes8);
                    case 9
                        imshow(final_B, 'Parent', app.UIAxes9);
                    case 10
                        imshow(final_B, 'Parent', app.UIAxes10);
                end

                pause(6);

            end

        end

        % Value changed function: RotateDropDown
        function RotateDropDownValueChanged(app, event)
            value = app.RotateDropDown.Value;
            switch value
                case '90'
                    app.rot_value = 90;
                case '180'
                    app.rot_value = 180;
                case '270'
                    app.rot_value = 270;
                case '-90'
                    app.rot_value = -90;
                case '-180'
                    app.rot_value = -180;
                case '-270'
                    app.rot_value = -270;
                case '0'
                    app.rot_value = 0;
            end

            app.selectedTab = app.TabGroup.SelectedTab;

            switch app.selectedTab.Title
                case 'Tab1'
                    currentImage = app.UIAxes1.Children.CData;

                    rotatedImage = imrotate(currentImage, app.rot_value);

                    imshow(rotatedImage, 'Parent', app.UIAxes1);
                case 'Tab2'
                    currentImage = app.UIAxes2.Children.CData;

                    rotatedImage = imrotate(currentImage, app.rot_value);

                    imshow(rotatedImage, 'Parent', app.UIAxes2);
                case 'Tab3'
                    currentImage = app.UIAxes3.Children.CData;

                    rotatedImage = imrotate(currentImage, app.rot_value);

                    imshow(rotatedImage, 'Parent', app.UIAxes3);
                case 'Tab4'
                    currentImage = app.UIAxes4.Children.CData;

                    rotatedImage = imrotate(currentImage, app.rot_value);

                    imshow(rotatedImage, 'Parent', app.UIAxes4);
                case 'Tab5'
                    currentImage = app.UIAxes5.Children.CData;

                    rotatedImage = imrotate(currentImage, app.rot_value);

                    imshow(rotatedImage, 'Parent', app.UIAxes5);
                case 'Tab6'
                    currentImage = app.UIAxes6.Children.CData;

                    rotatedImage = imrotate(currentImage, app.rot_value);

                    imshow(rotatedImage, 'Parent', app.UIAxes6);
                case 'Tab7'
                    currentImage = app.UIAxes7.Children.CData;

                    rotatedImage = imrotate(currentImage, app.rot_value);

                    imshow(rotatedImage, 'Parent', app.UIAxes7);
                case 'Tab8'
                    currentImage = app.UIAxes8.Children.CData;

                    rotatedImage = imrotate(currentImage, app.rot_value);

                    imshow(rotatedImage, 'Parent', app.UIAxes8);
                case 'Tab9'
                    currentImage = app.UIAxes9.Children.CData;

                    rotatedImage = imrotate(currentImage, app.rot_value);

                    imshow(rotatedImage, 'Parent', app.UIAxes9);
                case 'Tab10'
                    currentImage = app.UIAxes10.Children.CData;

                    rotatedImage = imrotate(currentImage, app.rot_value);

                    imshow(rotatedImage, 'Parent', app.UIAxes10);
            end
        end

        % Drop down opening function: RotateDropDown
        function RotateDropDownOpening(app, event)
            app.rot_value = 0;
        end

        % Button pushed function: downloadButton
        function downloadButtonPushed(app, event)
            app.selectedTab = app.TabGroup.SelectedTab;

            switch app.selectedTab.Title
                case 'Tab1'

                    fig = figure();
                    currentImage = app.UIAxes1.Children.CData;

                    ax = axes(fig);
                    imshow(currentImage, 'Parent', ax);
                    set(fig, 'Visible', 'off');

                    [filename, pathname] = uiputfile('*.png', 'Save As');
                    if isequal(filename, 0) || isequal(pathname, 0)
                        return;
                    end

                    imwrite(currentImage, fullfile(pathname, filename), 'png', 'Compression', 'none');

                    close(fig);

                case 'Tab2'
                    fig = figure();
                    currentImage = app.UIAxes2.Children.CData;

                    ax = axes(fig);
                    imshow(currentImage, 'Parent', ax);
                    set(fig, 'Visible', 'off');

                    [filename, pathname] = uiputfile('*.png', 'Save As');
                    if isequal(filename, 0) || isequal(pathname, 0)
                        return;
                    end

                    imwrite(currentImage, fullfile(pathname, filename), 'png', 'Compression', 'none');

                    close(fig);
                case 'Tab3'
                    fig = figure();
                    currentImage = app.UIAxes3.Children.CData;

                    ax = axes(fig);
                    imshow(currentImage, 'Parent', ax);
                    set(fig, 'Visible', 'off');

                    [filename, pathname] = uiputfile('*.png', 'Save As');
                    if isequal(filename, 0) || isequal(pathname, 0)
                        return;
                    end

                    imwrite(currentImage, fullfile(pathname, filename), 'png', 'Compression', 'none');

                    close(fig);
                case 'Tab4'
                    fig = figure();
                    currentImage = app.UIAxes4.Children.CData;

                    ax = axes(fig);
                    imshow(currentImage, 'Parent', ax);
                    set(fig, 'Visible', 'off');

                    [filename, pathname] = uiputfile('*.png', 'Save As');
                    if isequal(filename, 0) || isequal(pathname, 0)
                        return;
                    end

                    imwrite(currentImage, fullfile(pathname, filename), 'png', 'Compression', 'none');

                    close(fig);
                case 'Tab5'
                    fig = figure();
                    currentImage = app.UIAxes5.Children.CData;

                    ax = axes(fig);
                    imshow(currentImage, 'Parent', ax);
                    set(fig, 'Visible', 'off');

                    [filename, pathname] = uiputfile('*.png', 'Save As');
                    if isequal(filename, 0) || isequal(pathname, 0)
                        return;
                    end

                    imwrite(currentImage, fullfile(pathname, filename), 'png', 'Compression', 'none');

                    close(fig);
                case 'Tab6'
                    fig = figure();
                    currentImage = app.UIAxes6.Children.CData;

                    ax = axes(fig);
                    imshow(currentImage, 'Parent', ax);
                    set(fig, 'Visible', 'off');

                    [filename, pathname] = uiputfile('*.png', 'Save As');
                    if isequal(filename, 0) || isequal(pathname, 0)
                        return;
                    end

                    imwrite(currentImage, fullfile(pathname, filename), 'png', 'Compression', 'none');

                    close(fig);
                case 'Tab7'
                    fig = figure();
                    currentImage = app.UIAxes7.Children.CData;

                    ax = axes(fig);
                    imshow(currentImage, 'Parent', ax);
                    set(fig, 'Visible', 'off');

                    [filename, pathname] = uiputfile('*.png', 'Save As');
                    if isequal(filename, 0) || isequal(pathname, 0)
                        return;
                    end

                    imwrite(currentImage, fullfile(pathname, filename), 'png', 'Compression', 'none');

                    close(fig);
                case 'Tab8'
                    fig = figure();
                    currentImage = app.UIAxes8.Children.CData;

                    ax = axes(fig);
                    imshow(currentImage, 'Parent', ax);
                    set(fig, 'Visible', 'off');

                    [filename, pathname] = uiputfile('*.png', 'Save As');
                    if isequal(filename, 0) || isequal(pathname, 0)
                        return;
                    end

                    imwrite(currentImage, fullfile(pathname, filename), 'png', 'Compression', 'none');

                    close(fig);
                case 'Tab9'
                    fig = figure();
                    currentImage = app.UIAxes9.Children.CData;

                    ax = axes(fig);
                    imshow(currentImage, 'Parent', ax);
                    set(fig, 'Visible', 'off');

                    [filename, pathname] = uiputfile('*.png', 'Save As');
                    if isequal(filename, 0) || isequal(pathname, 0)
                        return;
                    end

                    imwrite(currentImage, fullfile(pathname, filename), 'png', 'Compression', 'none');

                    close(fig);
                case 'Tab10'
                    fig = figure();
                    currentImage = app.UIAxes10.Children.CData;

                    ax = axes(fig);
                    imshow(currentImage, 'Parent', ax);
                    set(fig, 'Visible', 'off');

                    [filename, pathname] = uiputfile('*.png','Save As');
                    if isequal(filename, 0) || isequal(pathname, 0)
                        return;
                    end

                    imwrite(currentImage, fullfile(pathname, filename), 'png', 'Compression', 'none');

                    close(fig);
            end

        end

        % Button pushed function: binarizeButton
        function binarizeButtonPushed(app, event)
            selectedTabTitle=app.TabGroup.SelectedTab.Title;
            switch selectedTabTitle
                case 'Tab1'
                    currentImage = app.UIAxes1.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = imbinarize(currentImage,'adaptive','ForegroundPolarity','dark');
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes1)


                case 'Tab2'
                    currentImage = app.UIAxes2.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = imbinarize(currentImage,'adaptive','ForegroundPolarity','dark');
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes2)


                case 'Tab3'
                    currentImage = app.UIAxes3.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = imbinarize(currentImage,'adaptive','ForegroundPolarity','dark');
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes3)


                case 'Tab4'
                    currentImage = app.UIAxes4.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = imbinarize(currentImage,'adaptive','ForegroundPolarity','dark');
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes4)


                case 'Tab5'
                    currentImage = app.UIAxes5.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = imbinarize(currentImage,'adaptive','ForegroundPolarity','dark');
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes5)


                case 'Tab6'
                    currentImage = app.UIAxes6.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = imbinarize(currentImage,'adaptive','ForegroundPolarity','dark');
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes6)


                case 'Tab7'
                    currentImage = app.UIAxes7.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = imbinarize(currentImage,'adaptive','ForegroundPolarity','dark');
                    currentImage=app.output;
                    currentImage = app.UIAxes7.Children.CData;
                    imshow(currentImage, 'Parent', app.UIAxes7)


                case 'Tab8'
                    currentImage = app.UIAxes8.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = imbinarize(currentImage,'adaptive','ForegroundPolarity','dark');
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes8)


                case 'Tab9'
                    currentImage = app.UIAxes9.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = imbinarize(currentImage,'adaptive','ForegroundPolarity','dark');
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes9)


                case 'Tab10'
                    currentImage = app.UIAxes10.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = imbinarize(currentImage,'adaptive','ForegroundPolarity','dark');
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes10)

            end
        end

        % Button pushed function: histogrameqButton
        function histogrameqButtonPushed(app, event)
            selectedTabTitle=app.TabGroup.SelectedTab.Title;
            switch selectedTabTitle
                case 'Tab1'
                    currentImage = app.UIAxes1.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = adapthisteq(currentImage);
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes1)


                case 'Tab2'
                    currentImage = app.UIAxes2.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = adapthisteq(currentImage);                   
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes2)


                case 'Tab3'
                    currentImage = app.UIAxes3.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = adapthisteq(currentImage);
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes3)


                case 'Tab4'
                    currentImage = app.UIAxes4.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = adapthisteq(currentImage);
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes4)


                case 'Tab5'
                    currentImage = app.UIAxes5.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = adapthisteq(currentImage);
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes5)


                case 'Tab6'
                    currentImage = app.UIAxes6.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = adapthisteq(currentImage);
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes6)


                case 'Tab7'
                    currentImage = app.UIAxes7.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = adapthisteq(currentImage);
                    currentImage=app.output;
                    currentImage = app.UIAxes7.Children.CData;
                    imshow(currentImage, 'Parent', app.UIAxes7)


                case 'Tab8'
                    currentImage = app.UIAxes8.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = adapthisteq(currentImage);
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes8)


                case 'Tab9'
                    currentImage = app.UIAxes9.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = adapthisteq(currentImage);
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes9)


                case 'Tab10'
                    currentImage = app.UIAxes10.Children.CData;
                    currentImage=im2double(currentImage);
                    app.output = adapthisteq(currentImage);
                    currentImage=app.output;
                    imshow(currentImage, 'Parent', app.UIAxes10)

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadImagesButton = uibutton(app.UIFigure, 'push');
            app.UploadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImagesButtonPushed, true);
            app.UploadImagesButton.Text = 'Upload Images';
            app.ScanButton = uibutton(app.UIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.downloadButton = uibutton(app.UIFigure, 'push');
            app.downloadButton.ButtonPushedFcn = createCallbackFcn(app, @downloadButtonPushed, true);
            app.downloadButton.Text = 'download';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Tab1';
            app.UIAxes1 = uiaxes(app.Tab1);
            title(app.UIAxes1, 'Image 1')
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes2 = uiaxes(app.Tab2);
            title(app.UIAxes2, 'Image 2')
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes3 = uiaxes(app.Tab3);
            title(app.UIAxes3, 'Image 3')
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.UIAxes4 = uiaxes(app.Tab4);
            title(app.UIAxes4, 'Image 4')
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.UIAxes5 = uiaxes(app.Tab5);
            title(app.UIAxes5, 'Image 5')
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Tab6';
            app.UIAxes6 = uiaxes(app.Tab6);
            title(app.UIAxes6, 'Image 6')
            app.Tab7 = uitab(app.TabGroup);
            app.Tab7.Title = 'Tab7';
            app.UIAxes7 = uiaxes(app.Tab7);
            title(app.UIAxes7, 'Image 7')
            app.Tab8 = uitab(app.TabGroup);
            app.Tab8.Title = 'Tab8';
            app.UIAxes8 = uiaxes(app.Tab8);
            title(app.UIAxes8, 'Image 8')
            app.Tab9 = uitab(app.TabGroup);
            app.Tab9.Title = 'Tab9';
            app.UIAxes9 = uiaxes(app.Tab9);
            title(app.UIAxes9, 'Image 9')
            app.Tab10 = uitab(app.TabGroup);
            app.Tab10.Title = 'Tab10';
            app.UIAxes10 = uiaxes(app.Tab10);
            title(app.UIAxes10, 'Image 10')
            app.ICCCAMSCANNERLabel = uilabel(app.UIFigure);
            app.ICCCAMSCANNERLabel.Text = 'ICC CAM SCANNER';
            app.exitButton = uibutton(app.UIFigure, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exitButton.Text = 'exit';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'EE409 güncel', 'Logo.png');
            app.binarizeButton = uibutton(app.UIFigure, 'push');
            app.binarizeButton.ButtonPushedFcn = createCallbackFcn(app, @binarizeButtonPushed, true);
            app.binarizeButton.Text = 'binarize';
            app.histogrameqButton = uibutton(app.UIFigure, 'push');
            app.histogrameqButton.ButtonPushedFcn = createCallbackFcn(app, @histogrameqButtonPushed, true);
            app.histogrameqButton.Text = 'histogram eq';
            app.RotateDropDownLabel = uilabel(app.UIFigure);
            app.RotateDropDownLabel.Text = 'Rotate';
            app.RotateDropDown = uidropdown(app.UIFigure);
            app.RotateDropDown.Items = {'90', '180', '270', '0', '-90', '-180', '-270'};
            app.RotateDropDown.DropDownOpeningFcn = createCallbackFcn(app, @RotateDropDownOpening, true);
            app.RotateDropDown.ValueChangedFcn = createCallbackFcn(app, @RotateDropDownValueChanged, true);
            app.RotateDropDown.Value = '90';
            app.SelectedgesiLabel = uilabel(app.UIFigure);
            app.SelectedgesiLabel.Text = 'Select the upper left, lower left, lower right and upper right edges respectively.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ee409_project_yeni

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end